classdef ScopeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MessageBox            matlab.ui.control.TextArea
        ConnectviaVISAButton  matlab.ui.control.StateButton
        VISADevBox            matlab.ui.control.ListBox
        ForceTrigButton       matlab.ui.control.Button
        CH4FocusButton        matlab.ui.control.StateButton
        CH3FocusButton        matlab.ui.control.StateButton
        CH2FocusButton        matlab.ui.control.StateButton
        CH1FocusButton        matlab.ui.control.StateButton
        TriggerMode           matlab.ui.control.ListBox
        RefreshAllButton      matlab.ui.control.Button
        SetTriggerButton      matlab.ui.control.Button
        TriggerEdge           matlab.ui.control.ListBox
        TriggerChannel        matlab.ui.control.ListBox
        TriggerLevelBox       matlab.ui.control.NumericEditField
        EditField_6Label      matlab.ui.control.Label
        CH4ScaleButton        matlab.ui.control.Button
        CH3ScaleButton        matlab.ui.control.Button
        CH2ScaleButton        matlab.ui.control.Button
        CH1ScaleBox           matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        CH3ScaleBox           matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        CH2ScaleBox           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        CH4ScaleBox           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        CH1ScaleButton        matlab.ui.control.Button
        MainTimebaseButton    matlab.ui.control.Button
        MainTimebaseBox       matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        instDev % Description
        previewTimer
        channelScaleBox
        channelScaleButton
        channelFocusButton
        preWfmPlot
        triggerLevelPlot
    end 
    
    methods (Access = private)
        function previewUpdate(app, obj, event)
            [prewfm, pretime] = app.instDev.getWfmPreview(1);
            app.MessageBox.Value = string(datetime);

            app.preWfmPlot.XData = pretime;
            app.preWfmPlot.YData = prewfm;
            app.triggerLevelPlot.XData = [min(pretime), max(pretime)];
            app.triggerLevelPlot.YData = [app.instDev.triggerLevel, app.instDev.triggerLevel];
            
            set(app.UIAxes, 'XLim', [min(pretime) max(pretime)]);
            set(app.UIAxes, 'YLim', [min(prewfm)-0.1*(max(prewfm)-min(prewfm)) max(prewfm)+0.1*(max(prewfm)-min(prewfm))]);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('..\'));

            list = pyrunfile('VISAList.py','list');
            list = cell(list);
        
            if(isempty(list))
                error('No valid VISA device detected.');
            end

            for index = 1:length(list)
                app.VISADevBox.Items = [app.VISADevBox.Items  string(list(index))];                    
            end
    
            app.previewTimer = timer("ExecutionMode","fixedRate","Period",1, ...
                "BusyMode","drop","TimerFcn",@app.previewUpdate);

            %TODO: Pack these up as a axes-initialization function, so you
            %can add some extra line anytime you want.
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            title(app.UIAxes, 'Waveform Preview');
            xlabel(app.UIAxes, 'Time / s');
            ylabel(app.UIAxes, 'Voltage / V');
            app.preWfmPlot = plot(app.UIAxes, 1);
            app.triggerLevelPlot = plot(app.UIAxes, 1);
            app.triggerLevelPlot.LineStyle = '--';
            app.triggerLevelPlot.LineWidth = 1.5;
            app.triggerLevelPlot.Marker = 'hexagram';

        end

        % Value changed function: ConnectviaVISAButton
        function ConnectviaVISAButtonValueChanged(app, event)
            app.channelScaleBox = [app.CH1ScaleBox, app.CH2ScaleBox, app.CH3ScaleBox, app.CH4ScaleBox];
            app.channelScaleButton = [app.CH1ScaleButton, app.CH2ScaleButton, app.CH3ScaleButton, app.CH4ScaleButton];
            app.channelFocusButton = [app.CH1FocusButton, app.CH2FocusButton, app.CH3FocusButton, app.CH4FocusButton];

            value = app.ConnectviaVISAButton.Value;
            if (value == true)
                app.instDev = Inst_DS1000ZE(app.VISADevBox.Value);      %%TODO: Support more models.
                app.MessageBox.Value = app.instDev.IDN;

                for i = app.instDev.channelN+1: 4
                    app.channelScaleBox(i).Enable = false;
                    app.channelScaleButton(i).Enable = false;
                    app.channelFocusButton(i).Enable = false;
                end

                app.TriggerChannel.Items = {};
                for i = 1:app.instDev.channelN
                    app.TriggerChannel.Items(i) = {append('CH ', num2str(i))};
                end
                
                app.RefreshAllButtonPushed();

            else
                app.instDev.disconnect();
            end
        end

        % Button pushed function: RefreshAllButton
        function RefreshAllButtonPushed(app, event)
            % Fetch the current setting on the oscilloscope.
            stop(app.previewTimer);
            
            app.instDev.refresh();
            app.MainTimebaseBox.Value = app.instDev.mainTimebase;
            for chan = 1:app.instDev.channelN
                app.channelScaleBox(chan).Value = app.instDev.yRange(chan);
                app.channelFocusButton(chan).Value = app.instDev.focus(chan);
            end
            app.TriggerEdge.ValueIndex = app.instDev.triggerEdge;
            app.TriggerMode.ValueIndex = app.instDev.triggerMode;
            app.TriggerChannel.ValueIndex = app.instDev.triggerSource;
            app.TriggerLevelBox.Value = app.instDev.triggerLevel;

            start(app.previewTimer);
        end

        % Button pushed function: SetTriggerButton
        function SetTriggerButtonPushed(app, event)
            stop(app.previewTimer);

            readback = app.instDev.setTrigger(app.TriggerMode.ValueIndex,...
                app.TriggerChannel.ValueIndex,...
                app.TriggerEdge.ValueIndex,...
                app.TriggerLevelBox.Value);

            app.TriggerEdge.ValueIndex = readback.edge;
            app.TriggerMode.ValueIndex = readback.mode;
            app.TriggerChannel.ValueIndex = readback.chan;
            app.TriggerLevelBox.Value = readback.level;
            
            start(app.previewTimer);
        end

        % Button pushed function: CH1ScaleButton
        function CH1ScaleButtonPushed(app, event)
            stop(app.previewTimer);
            readback = app.instDev.setChannelRange(1, app.CH1ScaleBox.Value);
            app.CH1ScaleBox.Value = readback;
            start(app.previewTimer);
        end

        % Button pushed function: CH2ScaleButton
        function CH2ScaleButtonPushed(app, event)
            stop(app.previewTimer);
            readback = app.instDev.setChannelRange(2, app.CH1ScaleBox.Value);
            app.CH2ScaleBox.Value = readback;
            start(app.previewTimer);
        end

        % Button pushed function: CH3ScaleButton
        function CH3ScaleButtonPushed(app, event)
            stop(app.previewTimer);
            readback = app.instDev.setChannelRange(3, app.CH1ScaleBox.Value);
            app.CH3ScaleBox.Value = readback;
            start(app.previewTimer);
        end

        % Button pushed function: CH4ScaleButton
        function CH4ScaleButtonPushed(app, event)
            stop(app.previewTimer);
            readback = app.instDev.setChannelRange(4, app.CH1ScaleBox.Value);
            app.CH4ScaleBox.Value = readback;
            start(app.previewTimer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.MainTimebaseBox = uieditfield(app.GridLayout, 'numeric');
            app.MainTimebaseBox.Value = 0.001;
            app.MainTimebaseButton = uibutton(app.GridLayout, 'push');
            app.MainTimebaseButton.Text = 'Main Timebase (s)';
            app.CH1ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH1ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH1ScaleButtonPushed, true);
            app.CH1ScaleButton.Text = 'CHAN 1 Scale (V/Div)';
            app.EditField_2Label = uilabel(app.GridLayout);
            app.EditField_2Label.Text = '';
            app.CH4ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH4ScaleBox.Value = 1;
            app.EditField_3Label = uilabel(app.GridLayout);
            app.EditField_3Label.Text = '';
            app.CH2ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH2ScaleBox.Value = 1;
            app.EditField_4Label = uilabel(app.GridLayout);
            app.EditField_4Label.Text = '';
            app.CH3ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH3ScaleBox.Value = 1;
            app.EditField_5Label = uilabel(app.GridLayout);
            app.EditField_5Label.Text = '';
            app.CH1ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH1ScaleBox.Value = 1;
            app.CH2ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH2ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH2ScaleButtonPushed, true);
            app.CH2ScaleButton.Text = 'CHAN 2 Scale (V/Div)';
            app.CH3ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH3ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH3ScaleButtonPushed, true);
            app.CH3ScaleButton.Text = 'CHAN 3 Scale (V/Div)';
            app.CH4ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH4ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH4ScaleButtonPushed, true);
            app.CH4ScaleButton.Text = 'CHAN 4 Scale (V/Div)';
            app.EditField_6Label = uilabel(app.GridLayout);
            app.EditField_6Label.Text = '';
            app.TriggerLevelBox = uieditfield(app.GridLayout, 'numeric');
            app.TriggerChannel = uilistbox(app.GridLayout);
            app.TriggerChannel.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.TriggerChannel.Value = 'CH 1';
            app.TriggerEdge = uilistbox(app.GridLayout);
            app.TriggerEdge.Items = {'POS EDGE', 'NEG EDGE', 'ANY EDGE'};
            app.TriggerEdge.Value = 'POS EDGE';
            app.SetTriggerButton = uibutton(app.GridLayout, 'push');
            app.SetTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @SetTriggerButtonPushed, true);
            app.SetTriggerButton.Text = 'Set Trigger';
            app.RefreshAllButton = uibutton(app.GridLayout, 'push');
            app.RefreshAllButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAllButtonPushed, true);
            app.RefreshAllButton.Text = 'Refresh All';
            app.TriggerMode = uilistbox(app.GridLayout);
            app.TriggerMode.Items = {'AUTO', 'NORM', 'SINGLE'};
            app.TriggerMode.Value = 'AUTO';
            app.CH1FocusButton = uibutton(app.GridLayout, 'state');
            app.CH1FocusButton.Text = 'CH1 Record';
            app.CH2FocusButton = uibutton(app.GridLayout, 'state');
            app.CH2FocusButton.Text = 'CH2 Record';
            app.CH3FocusButton = uibutton(app.GridLayout, 'state');
            app.CH3FocusButton.Text = 'CH3 Record';
            app.CH4FocusButton = uibutton(app.GridLayout, 'state');
            app.CH4FocusButton.Text = 'CH4 Record';
            app.ForceTrigButton = uibutton(app.GridLayout, 'push');
            app.ForceTrigButton.Text = 'Force Trig';
            app.VISADevBox = uilistbox(app.GridLayout);
            app.VISADevBox.Items = {};
            app.VISADevBox.Value = {};
            app.ConnectviaVISAButton = uibutton(app.GridLayout, 'state');
            app.ConnectviaVISAButton.ValueChangedFcn = createCallbackFcn(app, @ConnectviaVISAButtonValueChanged, true);
            app.ConnectviaVISAButton.Text = 'Connect via VISA';
            app.MessageBox = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end