classdef ScopeWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ReadNowButton         matlab.ui.control.Button
        RecordPathBox         matlab.ui.control.EditField
        RecordFormat          matlab.ui.control.ListBox
        StopTimeBox           matlab.ui.control.EditField
        LongScopeLamp         matlab.ui.control.Lamp
        LongScopeSwitch       matlab.ui.control.RockerSwitch
        LongScopeProgressBar  matlab.ui.control.LinearGauge
        SampIntervalBox       matlab.ui.control.EditField
        MemDepthList          matlab.ui.control.ListBox
        MessageBox            matlab.ui.control.TextArea
        ConnectviaVISAButton  matlab.ui.control.StateButton
        VISADevBox            matlab.ui.control.ListBox
        ForceTrigButton       matlab.ui.control.Button
        CH4FocusButton        matlab.ui.control.StateButton
        CH3FocusButton        matlab.ui.control.StateButton
        CH2FocusButton        matlab.ui.control.StateButton
        CH1FocusButton        matlab.ui.control.StateButton
        TriggerMode           matlab.ui.control.ListBox
        RefreshAllButton      matlab.ui.control.Button
        SetTriggerButton      matlab.ui.control.Button
        TriggerEdge           matlab.ui.control.ListBox
        TriggerChannel        matlab.ui.control.ListBox
        TriggerLevelBox       matlab.ui.control.NumericEditField
        EditField_6Label      matlab.ui.control.Label
        CH4ScaleButton        matlab.ui.control.Button
        CH3ScaleButton        matlab.ui.control.Button
        CH2ScaleButton        matlab.ui.control.Button
        CH1ScaleBox           matlab.ui.control.NumericEditField
        EditField_5Label      matlab.ui.control.Label
        CH3ScaleBox           matlab.ui.control.NumericEditField
        EditField_4Label      matlab.ui.control.Label
        CH2ScaleBox           matlab.ui.control.NumericEditField
        EditField_3Label      matlab.ui.control.Label
        CH4ScaleBox           matlab.ui.control.NumericEditField
        EditField_2Label      matlab.ui.control.Label
        CH1ScaleButton        matlab.ui.control.Button
        MainTimebaseButton    matlab.ui.control.Button
        MainTimebaseBox       matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    % PROBLEM: Much too shity.
    % Consider:
    %   1. Add a preview toggle switch, so you don't have to pause the
    %   preview sampling timer everytime when a button is pressed.
    %
    %   2. Move some functions to other files.
    %
    %   3. Just give up Matlab and swap to python or something.
    %
    %   4. Don't forget the last point of wfm, BOTH preview and full data
    %   is ignored due to its inaccuracy. So when you fetch 12000 pts, you
    %   actually get 11999 pts. You may change this 'feature' in DS1000ZE
    %   class, and when you do so, don't forget to update this file too.

    properties (Access = private)
        instDev % Description

        previewSampTimer
        longScopeSampTimer
        longScopeStopTimer
        
        longScopeStartTime
        longScopeEndTime
        longScopeDurationSecond

        longScopeEnabled

        channelScaleBox
        channelScaleButton
        channelFocusButton

        preWfmPlot
        triggerLevelPlot
        floatingAxes

        colorRed = [0.85,0.33,0.10]
        colorYellow = [0.93,0.69,0.13];
        colorGreen = [0.39,0.83,0.07];
    end
    
    methods (Access = private)
        function previewUpdate(app, ~, ~)
            % Update the plot of the focused channels, and turn the others
            % invisible.
            for chan = 1:app.instDev.channelN
                if (app.instDev.focus(chan) == 1)
                    [prewfm, pretime] = app.instDev.getWfmPreview(chan);
                    app.preWfmPlot(chan).XData = pretime;
                    app.preWfmPlot(chan).YData = prewfm;
                    app.preWfmPlot(chan).Visible = 'on';

                    set(app.UIAxes, 'XLim', [min(pretime) max(pretime)]);
                    set(app.UIAxes, 'YLim', [min(prewfm)-0.1*(max(prewfm)-min(prewfm)) max(prewfm)+0.1*(max(prewfm)-min(prewfm))]);
                else
                    app.preWfmPlot(chan).Visible = 'off';
                end
            end
            
            % Update the long scope progress bar and remaining time box.
            if(app.longScopeEnabled)
                timePassed = datetime() - app.longScopeStartTime;
                timePassed = seconds(timePassed);
                app.LongScopeProgressBar.Value = timePassed / app.longScopeDurationSecond;
                app.MessageBox.Value = append('Remaining:',...
                    newline, string(app.longScopeEndTime - datetime()));
            end

            % Update trigger line.
            app.triggerLevelPlot.XData = [min(pretime), max(pretime)];
            app.triggerLevelPlot.YData = [app.instDev.triggerLevel, app.instDev.triggerLevel];
        end
        
        function updateMemDepthList(app)
            onChannelN = 0;
            for chanOn = app.instDev.focus
                if (chanOn == 1)
                    onChannelN = onChannelN + 1;
                end
            end
            
        %    app.MemDepthList.Items = {};
        %    for i = length(app.instDev.memDepth(onChannelN,:))
        %        app.MemDepthList.Items(i) = {char(string(app.instDev.memDepth(onChannelN,i)))};
        %    end
        end
        
        function longScope(app, ~, ~)
            tic
            stop(app.previewSampTimer);
            app.LongScopeLamp.Color = app.colorYellow;
            app.instDev.setRunStop(0);
            %TO CONFIRM: Is the time interval after setStop long enough for
            %the oscilloscope to write the full memory depth?
          
            % Get enabled channels
            enabledChan = [];
            enabledChanRange= [];
            enabledChanN = 0;
            for chan = 1:app.instDev.channelN
                if (app.instDev.focus(chan) == 1)
                    enabledChan = [enabledChan chan];
                    enabledChanRange = [enabledChanRange app.instDev.yRange(chan)];
                    enabledChanN = enabledChanN + 1;
                end
            end

            % Read waveform from every enabled channel.
            depth = str2double(app.MemDepthList.Value);
            wfm = zeros(enabledChanN, depth-1);
            time = zeros(1,depth-1);
            for chan = enabledChan
                [wfm(chan,:),time] = app.instDev.getWfm(chan);      % IGNORE THE LAST POINT
            end

            % Record and generate preview of read waveform.
            recordPath = app.RecordPathBox.Value;
            recordCSV(recordPath, [time;wfm], enabledChan, enabledChanRange);
            for i = 1:enabledChanN
                plot(app.floatingAxes, time, wfm(i,:));
                hold on
            end
            hold off

            % Restart oscilloscope sweeping.
            app.instDev.setRunStop(1);
            app.LongScopeLamp.Color = app.colorGreen;
            start(app.previewSampTimer);
            toc
        end
        
        function longScopeStop(app, ~, ~)
            stop(app.longScopeSampTimer);
            app.LongScopeSwitch.ValueIndex = 1;
            app.LongScopeLamp.Color = app.colorRed;
            app.longScopeEnabled = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Annotate this line when packing the app.
            %addpath(genpath('..\'));

            list = pyrunfile('VISAList.py','list');
            list = cell(list);
        
            if(isempty(list))
                disp('No valid VISA device detected.');
            end

            for index = 1:length(list)
                app.VISADevBox.Items = [app.VISADevBox.Items  string(list(index))];                    
            end
    
            app.previewSampTimer = timer("ExecutionMode","fixedRate","Period",1, ...
                "BusyMode","drop","TimerFcn",@app.previewUpdate);
            app.longScopeSampTimer =  timer("ExecutionMode","fixedRate", ...
                "BusyMode","drop","TimerFcn",@app.longScope);
            app.longScopeStopTimer = timer("BusyMode","queue",...
                'ExecutionMode','singleShot','TimerFcn',@app.longScopeStop);

            %TODO: Pack these up as a axes-initialization function, so you
            %can add some extra line anytime you want.
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');
            title(app.UIAxes, 'Waveform Preview');
            xlabel(app.UIAxes, 'Time / s');
            ylabel(app.UIAxes, 'Voltage / V');
            app.preWfmPlot = plot(app.UIAxes, 1,1,1,1,1,1,1,1);
            app.triggerLevelPlot = plot(app.UIAxes, 1);
            app.triggerLevelPlot.LineStyle = '--';
            app.triggerLevelPlot.LineWidth = 1.5;
            app.triggerLevelPlot.Marker = 'hexagram';
            app.floatingAxes = axes;
            grid(app.floatingAxes, 'on');
        end

        % Value changed function: ConnectviaVISAButton
        function ConnectviaVISAButtonValueChanged(app, event)
            app.channelScaleBox = [app.CH1ScaleBox, app.CH2ScaleBox, app.CH3ScaleBox, app.CH4ScaleBox];
            app.channelScaleButton = [app.CH1ScaleButton, app.CH2ScaleButton, app.CH3ScaleButton, app.CH4ScaleButton];
            app.channelFocusButton = [app.CH1FocusButton, app.CH2FocusButton, app.CH3FocusButton, app.CH4FocusButton];

            value = app.ConnectviaVISAButton.Value;
            if (value == true)
                app.instDev = Inst_DS1000ZE(app.VISADevBox.Value);      %%TODO: Support more models.
                app.MessageBox.Value = app.instDev.IDN;

                % Adjust the panel to the actual number of channles of
                % certain oscilloscope.
                for i = app.instDev.channelN+1: 4
                    app.channelScaleBox(i).Enable = false;
                    app.channelScaleButton(i).Enable = false;
                    app.channelFocusButton(i).Enable = false;
                end

                % Adjust the items of trigger source list.
                app.TriggerChannel.Items = {};
                for i = 1:app.instDev.channelN
                    app.TriggerChannel.Items(i) = {append('CH ', num2str(i))};
                end
               
                % Load settings from the oscilloscope.
                %app.instDev.setRunStop(1);
                app.RefreshAllButtonPushed();
                
            else
                app.instDev.disconnect();
            end
        end

        % Button pushed function: RefreshAllButton
        function RefreshAllButtonPushed(app, event)
            % Fetch the current setting on the oscilloscope.
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            
            app.instDev.refresh();
            app.MainTimebaseBox.Value = app.instDev.mainTimebase;
            for chan = 1:app.instDev.channelN
                app.channelScaleBox(chan).Value = app.instDev.yRange(chan);
                app.channelFocusButton(chan).Value = app.instDev.focus(chan);
            end
            app.TriggerEdge.ValueIndex = app.instDev.triggerEdge;
            app.TriggerMode.ValueIndex = app.instDev.triggerMode;
            app.TriggerChannel.ValueIndex = app.instDev.triggerSource;
            app.TriggerLevelBox.Value = app.instDev.triggerLevel;

            % Channel could be manually toggled, MDEP list needs update.
            app.updateMemDepthList();

            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: MainTimebaseButton
        function MainTimebaseButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            
            timebase = app.MainTimebaseBox.Value;
            timebase = str2double(timebase);
            readback = app.instDev.setMainTimeBase(timebase);
            app.MainTimebaseBox.Value = num2str(readback);

            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: SetTriggerButton
        function SetTriggerButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);

            % Send trigger parameter and read result.
            readback = app.instDev.setTrigger(app.TriggerMode.ValueIndex,...
                app.TriggerChannel.ValueIndex,...
                app.TriggerEdge.ValueIndex,...
                app.TriggerLevelBox.Value);

            % Update the components with the actual setting of the
            % oscilloscope.
            app.TriggerEdge.ValueIndex = readback.edge;
            app.TriggerMode.ValueIndex = readback.mode;
            app.TriggerChannel.ValueIndex = readback.chan;
            app.TriggerLevelBox.Value = readback.level;
            
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: CH1ScaleButton
        function CH1ScaleButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            readback = app.instDev.setChannelRange(1, app.CH1ScaleBox.Value);
            app.CH1ScaleBox.Value = readback;
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: CH2ScaleButton
        function CH2ScaleButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            readback = app.instDev.setChannelRange(2, app.CH1ScaleBox.Value);
            app.CH2ScaleBox.Value = readback;
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: CH3ScaleButton
        function CH3ScaleButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            readback = app.instDev.setChannelRange(3, app.CH1ScaleBox.Value);
            app.CH3ScaleBox.Value = readback;
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: CH4ScaleButton
        function CH4ScaleButtonPushed(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            readback = app.instDev.setChannelRange(4, app.CH1ScaleBox.Value);
            app.CH4ScaleBox.Value = readback;
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Value changed function: CH1FocusButton
        function CH1FocusButtonValueChanged(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            value = app.CH1FocusButton.Value;
            readback = app.instDev.setChannelStatus(1, value);
            app.CH1FocusButton.Value = readback;
            app.updateMemDepthList();
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Value changed function: CH2FocusButton
        function CH2FocusButtonValueChanged(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            value = app.CH2FocusButton.Value;
            readback = app.instDev.setChannelStatus(2, value);
            app.CH2FocusButton.Value = readback;
            app.updateMemDepthList();
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Value changed function: CH3FocusButton
        function CH3FocusButtonValueChanged(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            value = app.CH3FocusButton.Value;
            readback = app.instDev.setChannelStatus(3, value);
            app.CH3FocusButton.Value = readback;
            app.updateMemDepthList();
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Value changed function: CH4FocusButton
        function CH4FocusButtonValueChanged(app, event)
            stop(app.previewSampTimer);
            stop(app.longScopeSampTimer);
            value = app.CH4FocusButton.Value;
            readback = app.instDev.setChannelStatus(4, value);
            app.CH4FocusButton.Value = readback;
            app.updateMemDepthList();
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Value changed function: LongScopeSwitch
        function LongScopeSwitchValueChanged(app, event)
            value = app.LongScopeSwitch.ValueIndex;
            stop(app.previewSampTimer);
            if (value == 2)
                % Read sampling period and converte stop time.
                app.longScopeSampTimer.Period = str2double(app.SampIntervalBox.Value);
                stopTimeStr = app.StopTimeBox.Value;
                stopTimeArray = strsplit(stopTimeStr, '-');
                stopTimeArray = str2double(stopTimeArray);
                app.longScopeEndTime = datetime(stopTimeArray);

                % Refresh and set Acquire Depth.
                app.RefreshAllButton();
                app.instDev.setAcquireDepth(app.MemDepthList.Value);

                % Start the stop timer first, than the sampling timer.
                startat(app.longScopeStopTimer, app.longScopeEndTime);
                start(app.longScopeSampTimer);
                app.longScopeStartTime = datetime('now');
                app.longScopeDurationSecond = app.longScopeEndTime - app.longScopeStartTime;
                app.longScopeDurationSecond = seconds(app.longScopeDurationSecond);

                % Turn the indicator green.
                app.LongScopeLamp.Color = app.colorGreen;
                app.longScopeEnabled = 1;
            else
                stop(app.longScopeSampTimer);
                stop(app.longScopeStopTimer);
                % Red.
                app.LongScopeLamp.Color = app.colorRed;
                app.longScopeEnabled = 0;
            end
            start(app.longScopeSampTimer);
            start(app.previewSampTimer);
        end

        % Button pushed function: ReadNowButton
        function ReadNowButtonPushed(app, event)
            app.longScope();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Waveform Preview')
            xlabel(app.UIAxes, 'Time / s')
            ylabel(app.UIAxes, 'Voltage / V')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.MainTimebaseBox = uieditfield(app.GridLayout, 'numeric');
            app.MainTimebaseBox.Value = 0.001;
            app.MainTimebaseButton = uibutton(app.GridLayout, 'push');
            app.MainTimebaseButton.ButtonPushedFcn = createCallbackFcn(app, @MainTimebaseButtonPushed, true);
            app.MainTimebaseButton.Text = 'Main Timebase (s)';
            app.CH1ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH1ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH1ScaleButtonPushed, true);
            app.CH1ScaleButton.Text = 'CHAN 1 Range(V)';
            app.EditField_2Label = uilabel(app.GridLayout);
            app.EditField_2Label.Text = '';
            app.CH4ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH4ScaleBox.Value = 1;
            app.EditField_3Label = uilabel(app.GridLayout);
            app.EditField_3Label.Text = '';
            app.CH2ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH2ScaleBox.Value = 1;
            app.EditField_4Label = uilabel(app.GridLayout);
            app.EditField_4Label.Text = '';
            app.CH3ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH3ScaleBox.Value = 1;
            app.EditField_5Label = uilabel(app.GridLayout);
            app.EditField_5Label.Text = '';
            app.CH1ScaleBox = uieditfield(app.GridLayout, 'numeric');
            app.CH1ScaleBox.Value = 1;
            app.CH2ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH2ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH2ScaleButtonPushed, true);
            app.CH2ScaleButton.Text = 'CHAN 2 Range (V)';
            app.CH3ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH3ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH3ScaleButtonPushed, true);
            app.CH3ScaleButton.Text = 'CHAN 3 Range (V)';
            app.CH4ScaleButton = uibutton(app.GridLayout, 'push');
            app.CH4ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @CH4ScaleButtonPushed, true);
            app.CH4ScaleButton.Text = 'CHAN 4 Range (V)';
            app.EditField_6Label = uilabel(app.GridLayout);
            app.EditField_6Label.Text = '';
            app.TriggerLevelBox = uieditfield(app.GridLayout, 'numeric');
            app.TriggerChannel = uilistbox(app.GridLayout);
            app.TriggerChannel.Items = {'CH 1', 'CH 2', 'CH 3', 'CH 4'};
            app.TriggerChannel.Value = 'CH 1';
            app.TriggerEdge = uilistbox(app.GridLayout);
            app.TriggerEdge.Items = {'POS EDGE', 'NEG EDGE', 'ANY EDGE'};
            app.TriggerEdge.Value = 'POS EDGE';
            app.SetTriggerButton = uibutton(app.GridLayout, 'push');
            app.SetTriggerButton.ButtonPushedFcn = createCallbackFcn(app, @SetTriggerButtonPushed, true);
            app.SetTriggerButton.Text = 'Set Trigger';
            app.RefreshAllButton = uibutton(app.GridLayout, 'push');
            app.RefreshAllButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAllButtonPushed, true);
            app.RefreshAllButton.Text = 'Refresh All';
            app.TriggerMode = uilistbox(app.GridLayout);
            app.TriggerMode.Items = {'AUTO', 'NORM', 'SINGLE'};
            app.TriggerMode.Value = 'AUTO';
            app.CH1FocusButton = uibutton(app.GridLayout, 'state');
            app.CH1FocusButton.ValueChangedFcn = createCallbackFcn(app, @CH1FocusButtonValueChanged, true);
            app.CH1FocusButton.Text = 'CH1 Record';
            app.CH2FocusButton = uibutton(app.GridLayout, 'state');
            app.CH2FocusButton.ValueChangedFcn = createCallbackFcn(app, @CH2FocusButtonValueChanged, true);
            app.CH2FocusButton.Text = 'CH2 Record';
            app.CH3FocusButton = uibutton(app.GridLayout, 'state');
            app.CH3FocusButton.ValueChangedFcn = createCallbackFcn(app, @CH3FocusButtonValueChanged, true);
            app.CH3FocusButton.Text = 'CH3 Record';
            app.CH4FocusButton = uibutton(app.GridLayout, 'state');
            app.CH4FocusButton.ValueChangedFcn = createCallbackFcn(app, @CH4FocusButtonValueChanged, true);
            app.CH4FocusButton.Text = 'CH4 Record';
            app.ForceTrigButton = uibutton(app.GridLayout, 'push');
            app.ForceTrigButton.Text = 'Force Trig';
            app.VISADevBox = uilistbox(app.GridLayout);
            app.VISADevBox.Items = {};
            app.VISADevBox.Value = {};
            app.ConnectviaVISAButton = uibutton(app.GridLayout, 'state');
            app.ConnectviaVISAButton.ValueChangedFcn = createCallbackFcn(app, @ConnectviaVISAButtonValueChanged, true);
            app.ConnectviaVISAButton.Text = 'Connect via VISA';
            app.MessageBox = uitextarea(app.GridLayout);
            app.MemDepthList = uilistbox(app.GridLayout);
            app.MemDepthList.Items = {'12000', '120000', '12000000', '24000000'};
            app.MemDepthList.Value = '12000';
            app.SampIntervalBox = uieditfield(app.GridLayout, 'text');
            app.SampIntervalBox.Value = '20';
            app.LongScopeProgressBar = uigauge(app.GridLayout, 'linear');
            app.LongScopeProgressBar.Limits = [0 1];
            app.LongScopeProgressBar.MajorTicks = [0 0.25 0.5 0.75 1];
            app.LongScopeProgressBar.MajorTickLabels = {'0', '25%', '50%', '75%', '100%'};
            app.LongScopeSwitch = uiswitch(app.GridLayout, 'rocker');
            app.LongScopeSwitch.Items = {'Stop', 'Start'};
            app.LongScopeSwitch.ValueChangedFcn = createCallbackFcn(app, @LongScopeSwitchValueChanged, true);
            app.LongScopeSwitch.Value = 'Stop';
            app.LongScopeLamp = uilamp(app.GridLayout);
            app.StopTimeBox = uieditfield(app.GridLayout, 'text');
            app.StopTimeBox.Value = '2025-3-5-20-0-0';
            app.RecordFormat = uilistbox(app.GridLayout);
            app.RecordFormat.Items = {'.CSV'};
            app.RecordFormat.Value = '.CSV';
            app.RecordPathBox = uieditfield(app.GridLayout, 'text');
            app.RecordPathBox.Value = 'D:\Projects\LongScope\';
            app.ReadNowButton = uibutton(app.GridLayout, 'push');
            app.ReadNowButton.ButtonPushedFcn = createCallbackFcn(app, @ReadNowButtonPushed, true);
            app.ReadNowButton.Text = 'Read Now';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScopeWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end